classdef Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EditField_6                 matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        EqualButton_2               matlab.ui.control.Button
        ListBox_2                   matlab.ui.control.ListBox
        PlottingButtonGroup         matlab.ui.container.ButtonGroup
        equationButton              matlab.ui.control.RadioButton
        cosineButton                matlab.ui.control.RadioButton
        sineButton                  matlab.ui.control.RadioButton
        resultantButton             matlab.ui.control.RadioButton
        xyaxisButton                matlab.ui.control.RadioButton
        yaxisButton                 matlab.ui.control.RadioButton
        xaxisButton                 matlab.ui.control.RadioButton
        DeleteButton                matlab.ui.control.Button
        EnterButton                 matlab.ui.control.Button
        NoErrorsfoundLabel_2        matlab.ui.control.Label
        NoErrorsfoundLabel          matlab.ui.control.Label
        ErrorConsoleLabel_2         matlab.ui.control.Label
        ResultLabel_2               matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        EditField_4                 matlab.ui.control.EditField
        Y_axisLabel                 matlab.ui.control.Label
        X_axisLabel                 matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        EnterequationfunxoryxLabel  matlab.ui.control.Label
        ErrorConsoleLabel           matlab.ui.control.Label
        OperationLabel              matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        EditField                   matlab.ui.control.NumericEditField
        SimpleCalculatorLabel       matlab.ui.control.Label
        ArithmaticOperationsLabel   matlab.ui.control.Label
        EqualButton                 matlab.ui.control.Button
        ListBox                     matlab.ui.control.ListBox
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            app.OperationLabel.Text = app.ListBox.Value;
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            operation = app.ListBox.Value;
            num_1 = app.EditField.Value;
            num_2 = app.EditField_2.Value;
            if(operation == '+')
                app.Label_3.Text = string(num_1 + num_2);
            elseif(operation == '-')
                app.Label_3.Text = string(num_1 - num_2);
            elseif(operation == '*')
                app.Label_3.Text = string(num_1 * num_2);
            elseif(operation == '/')
                if(num_2 == 0)
                    app.NoErrorsfoundLabel_2.Text = 'Error division by zero';
                elseif(num_2 ~= 0)
                    app.Label_3.Text = string(num_1 / num_2);
                end
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.OperationLabel.Text = app.ListBox.Value;       
        end

        % Button pushed function: EqualButton_2
        function EqualButton_2Pushed(app, event)
            mat_1 = str2num(app.EditField_4.Value);
            mat_2 = str2num(app.EditField_5.Value);
            operation = app.ListBox_2.Value;
            if(operation == '+')
                app.Label_2.Text = num2str(mat_1 + mat_2);
            elseif(operation == '-')
                app.Label_2.Text = num2str(mat_1 - mat_2);
            elseif(operation == '*')
                app.Label_2.Text = num2str(mat_1 .* mat_2);
            elseif(operation == '/')
                if any(mat_2 == 0)
                    app.NoErrorsfoundLabel.Text = 'Error division by zero';
                elseif(mat_2 ~= 0)
                    app.Label_2.Text = num2str(mat_1 ./ mat_2);
                end
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            if(app.cosineButton.Value == true)
                theta = 0:0.1:360;
                plot(app.UIAxes,cosd(theta));
                title(app.UIAxes,'Cosine Wave');
            elseif(app.sineButton.Value == true)
                theta = 0:0.1:360;
                plot(app.UIAxes,sind(theta));
                title(app.UIAxes,'Sine Wave');
            elseif(app.equationButton.Value == true)
                equationString = app.EditField_6.Value;
                equationString = strrep(equationString, 'x', 'x.');
                x = linspace(-20, 20, 100);
                y = eval(equationString);
                plot(app.UIAxes, x, y, 'LineWidth', 2);
                title(app.UIAxes,'Equation Wave');
            elseif(app.xaxisButton.Value == true)
                x = str2num(app.EditField_4.Value);
                y = str2num(app.EditField_5.Value);
                y = zeros(size(y));
                plot(app.UIAxes, x, y, 'LineWidth', 2);
                title(app.UIAxes,'X-Axis');
            elseif(app.yaxisButton.Value == true)
                x = str2num(app.EditField_4.Value);
                y = str2num(app.EditField_5.Value);
                x = zeros(size(x));
                plot(app.UIAxes, x, y, 'LineWidth', 2);
                title(app.UIAxes,'Y-Axis');
            elseif(app.xyaxisButton.Value == true) 
                x = str2num(app.EditField_4.Value);
                y = str2num(app.EditField_5.Value);
                plot(app.UIAxes, x, y, 'LineWidth', 2);
                title(app.UIAxes,'Y-Axis'); 
            elseif(app.resultantButton.Value == true)
                x = str2num(app.EditField_4.Value);
                y = str2num(app.Label_2.Text);
                plot(app.UIAxes, x, y, 'LineWidth', 2);
                title(app.UIAxes,'Resultant');
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            cla(app.UIAxes);
            title(app.UIAxes,'Title');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'+', '-', '*', '/'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '+';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.ArithmaticOperationsLabel = uilabel(app.UIFigure);
            app.ArithmaticOperationsLabel.Text = 'Arithmatic Operations';
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 1;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2';
            app.OperationLabel = uilabel(app.UIFigure);
            app.OperationLabel.Text = 'Operation';
            app.ErrorConsoleLabel = uilabel(app.UIFigure);
            app.ErrorConsoleLabel.Text = 'Error Console';
            app.EnterequationfunxoryxLabel = uilabel(app.UIFigure);
            app.EnterequationfunxoryxLabel.Text = 'Enter equation fun(x) or y(x)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.X_axisLabel = uilabel(app.UIFigure);
            app.X_axisLabel.Text = 'X_axis';
            app.Y_axisLabel = uilabel(app.UIFigure);
            app.Y_axisLabel.Text = 'Y_axis';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = '[1 2 3]';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = '[1 2 3]';
            app.ResultLabel_2 = uilabel(app.UIFigure);
            app.ResultLabel_2.Text = 'Result';
            app.ErrorConsoleLabel_2 = uilabel(app.UIFigure);
            app.ErrorConsoleLabel_2.Text = 'Error Console';
            app.NoErrorsfoundLabel = uilabel(app.UIFigure);
            app.NoErrorsfoundLabel.Text = 'No Errors found';
            app.NoErrorsfoundLabel_2 = uilabel(app.UIFigure);
            app.NoErrorsfoundLabel_2.Text = 'No Errors found';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.PlottingButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingButtonGroup.Title = 'Plotting Button Group';
            app.xaxisButton = uiradiobutton(app.PlottingButtonGroup);
            app.xaxisButton.Text = 'x-axis';
            app.xaxisButton.Value = true;
            app.yaxisButton = uiradiobutton(app.PlottingButtonGroup);
            app.yaxisButton.Text = 'y-axis';
            app.xyaxisButton = uiradiobutton(app.PlottingButtonGroup);
            app.xyaxisButton.Text = 'x&y-axis';
            app.resultantButton = uiradiobutton(app.PlottingButtonGroup);
            app.resultantButton.Text = 'resultant';
            app.sineButton = uiradiobutton(app.PlottingButtonGroup);
            app.sineButton.Text = 'sine';
            app.cosineButton = uiradiobutton(app.PlottingButtonGroup);
            app.cosineButton.Text = 'cosine';
            app.equationButton = uiradiobutton(app.PlottingButtonGroup);
            app.equationButton.Text = 'equation';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'+', '-', '*', '/'};
            app.ListBox_2.Value = '+';
            app.EqualButton_2 = uibutton(app.UIFigure, 'push');
            app.EqualButton_2.ButtonPushedFcn = createCallbackFcn(app, @EqualButton_2Pushed, true);
            app.EqualButton_2.Text = 'Equal';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2 4 6';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Value = 'x^2+1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end