classdef status_image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GetImageButton               matlab.ui.control.Button
        Below60HappyAbove60SadLabel  matlab.ui.control.Label
        EnteraNumberEditField        matlab.ui.control.NumericEditField
        EnteraNumberEditFieldLabel   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)

        end

        % Value changed function: EnteraNumberEditField
        function EnteraNumberEditFieldValueChanged(app, event)
            global value;
            value = app.EnteraNumberEditField.Value;
        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            global value;  
            if(value <= 60)
                img = imread('happy.jpg'); 
                imshow(img,'Parent',app.UIAxes);
                title(app.UIAxes, 'Yaaaaaayy :)');
            elseif(value > 60)
                img = imread('Sad.jpg'); 
                imshow(img,'Parent',app.UIAxes);
                title(app.UIAxes, 'awww :(');
            end
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EnteraNumberEditFieldLabel = uilabel(app.UIFigure);
            app.EnteraNumberEditFieldLabel.Text = 'Enter a Number';
            app.EnteraNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnteraNumberEditField.ValueChangedFcn = createCallbackFcn(app, @EnteraNumberEditFieldValueChanged, true);
            app.Below60HappyAbove60SadLabel = uilabel(app.UIFigure);
            app.Below60HappyAbove60SadLabel.Text = {'Below 60 -> Happy'; 'Above 60 -> Sad'};
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = status_image(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end