classdef Traffic_Light_Arduino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ManualmodeSettingsPanel       matlab.ui.container.Panel
        GREENButton                   matlab.ui.control.Button
        YELLOWButton                  matlab.ui.control.Button
        REDButton                     matlab.ui.control.Button
        OutputPanel                   matlab.ui.container.Panel
        Switch_2                      matlab.ui.control.Switch
        Lamp_Red                      matlab.ui.control.Lamp
        Lamp_Yellow                   matlab.ui.control.Lamp
        Lamp_Green                    matlab.ui.control.Lamp
        TimeLabel                     matlab.ui.control.Label
        ChoosemodePanel               matlab.ui.container.Panel
        Switch                        matlab.ui.control.Switch
        AutomaticmodeSettingsPanel    matlab.ui.container.Panel
        DelayissecondsEditField       matlab.ui.control.NumericEditField
        DelayissecondsEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            app.TimeLabel.Visible = 'off';
            app.DelayissecondsEditField.Enable = 'off';
            app.AutomaticmodeSettingsPanel.Enable = 'off';   
            app.TimeLabel.Text = num2str(app.DelayissecondsEditField.Value);
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            red_pin = "D13";
            yellow_pin = "D12";
            green_pin = "D11";
            a_uno = arduino("COM11","Uno");
            configurePin(a_uno,red_pin,'DigitalOutput');
            configurePin(a_uno,yellow_pin,'DigitalOutput');
            configurePin(a_uno,green_pin,'DigitalOutput');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            local_state = app.Switch.Value;
            if local_state == "Manual"
                app.TimeLabel.Visible = 'off';
                app.DelayissecondsEditField.Enable = 'off';
                app.AutomaticmodeSettingsPanel.Enable = 'off';
                
                app.REDButton.Enable = 'on';
                app.GREENButton.Enable = 'on';
                app.YELLOWButton.Enable = 'on';
                app.ManualmodeSettingsPanel.Enable = 'on';
                
            elseif local_state == "Automatic"
                app.REDButton.Enable = 'off';
                app.GREENButton.Enable = 'off';
                app.YELLOWButton.Enable = 'off';
                app.ManualmodeSettingsPanel.Enable = 'off';
                
                app.TimeLabel.Visible = 'on';
                app.DelayissecondsEditField.Enable = 'on';
                app.AutomaticmodeSettingsPanel.Enable = 'on';    
            end
        end

        % Button pushed function: REDButton
        function REDButtonPushed(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            if app.Switch_2.Value == "START"
                app.Lamp_Red.Color = [1 0 0];
                app.Lamp_Yellow.Color = [0 0 0];
                app.Lamp_Green.Color = [0 0 0];
                writeDigitalPin(a_uno,red_pin,1)
                writeDigitalPin(a_uno,yellow_pin,0)
                writeDigitalPin(a_uno,green_pin,0)
            end
        end

        % Button pushed function: YELLOWButton
        function YELLOWButtonPushed(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            if app.Switch_2.Value == "START"
                app.Lamp_Red.Color = [0 0 0];
                app.Lamp_Yellow.Color = [1 1 0];
                app.Lamp_Green.Color = [0 0 0];
                writeDigitalPin(a_uno,red_pin,0)
                writeDigitalPin(a_uno,yellow_pin,1)
                writeDigitalPin(a_uno,green_pin,0)
            end
        end

        % Button pushed function: GREENButton
        function GREENButtonPushed(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            if app.Switch_2.Value == "START"
                app.Lamp_Red.Color = [0 0 0];
                app.Lamp_Yellow.Color = [0 0 0];
                app.Lamp_Green.Color = [0 1 0];
                writeDigitalPin(a_uno,red_pin,0)
                writeDigitalPin(a_uno,yellow_pin,0)
                writeDigitalPin(a_uno,green_pin,1)
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            global a_uno;
            global red_pin;
            global yellow_pin;
            global green_pin;
            if app.Switch_2.Value == "STOP"
                app.Lamp_Red.Color = [0 0 0];
                app.Lamp_Yellow.Color = [0 0 0];
                app.Lamp_Green.Color = [0 0 0];
                writeDigitalPin(a_uno,red_pin,0)
                writeDigitalPin(a_uno,yellow_pin,0)
                writeDigitalPin(a_uno,green_pin,0)
                app.Switch.Enable = 'on';
                app.TimeLabel.Text = num2str(app.DelayissecondsEditField.Value);
            elseif app.Switch_2.Value == "START" && app.Switch.Value == "Manual"
                app.Switch.Enable = 'off';
            elseif app.Switch_2.Value == "START" && app.Switch.Value == "Automatic"
                app.Switch.Enable = 'off';
                app.TimeLabel.Text = num2str(app.DelayissecondsEditField.Value);
                
                while app.Switch_2.Value == "START" && app.Switch.Value == "Automatic"
                
                    time_count = app.DelayissecondsEditField.Value;
                    app.TimeLabel.Text = num2str(app.DelayissecondsEditField.Value);
                    
                    while time_count > 0
                        if app.Switch_2.Value == "STOP"
                            break
                        end
                        REDButtonPushed(app,[])
                        pause(1);
                        time_count = time_count - 1;
                        app.TimeLabel.Text = num2str(time_count);
                    end
                    time_count = app.DelayissecondsEditField.Value;
                    app.TimeLabel.Text = num2str(app.DelayissecondsEditField.Value);
                    
                    while time_count > 0
                        if app.Switch_2.Value == "STOP"
                            break
                        end
                        YELLOWButtonPushed(app,[])
                        pause(1);
                        time_count = time_count - 1;
                        app.TimeLabel.Text = num2str(time_count);
                    end
                    
                    time_count = app.DelayissecondsEditField.Value;
                    app.TimeLabel.Text = num2str(app.DelayissecondsEditField.Value);
                    
                    while time_count > 0
                        if app.Switch_2.Value == "STOP"
                            break
                        end
                        GREENButtonPushed(app,[])
                        pause(1);
                        time_count = time_count - 1;
                        app.TimeLabel.Text = num2str(time_count);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutomaticmodeSettingsPanel = uipanel(app.UIFigure);
            app.AutomaticmodeSettingsPanel.Title = 'Automatic mode Settings';
            app.DelayissecondsEditFieldLabel = uilabel(app.AutomaticmodeSettingsPanel);
            app.DelayissecondsEditFieldLabel.Text = 'Delay is seconds';
            app.DelayissecondsEditField = uieditfield(app.AutomaticmodeSettingsPanel, 'numeric');
            app.DelayissecondsEditField.Value = 3;
            app.ChoosemodePanel = uipanel(app.UIFigure);
            app.ChoosemodePanel.Title = 'Choose mode';
            app.Switch = uiswitch(app.ChoosemodePanel, 'slider');
            app.Switch.Items = {'Manual', 'Automatic'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Manual';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.TimeLabel = uilabel(app.OutputPanel);
            app.TimeLabel.Text = 'Time';
            app.Lamp_Green = uilamp(app.OutputPanel);
            app.Lamp_Yellow = uilamp(app.OutputPanel);
            app.Lamp_Red = uilamp(app.OutputPanel);
            app.Switch_2 = uiswitch(app.OutputPanel, 'slider');
            app.Switch_2.Items = {'STOP', 'START'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'STOP';
            app.ManualmodeSettingsPanel = uipanel(app.UIFigure);
            app.ManualmodeSettingsPanel.Title = 'Manual mode Settings';
            app.REDButton = uibutton(app.ManualmodeSettingsPanel, 'push');
            app.REDButton.ButtonPushedFcn = createCallbackFcn(app, @REDButtonPushed, true);
            app.REDButton.Text = 'RED';
            app.YELLOWButton = uibutton(app.ManualmodeSettingsPanel, 'push');
            app.YELLOWButton.ButtonPushedFcn = createCallbackFcn(app, @YELLOWButtonPushed, true);
            app.YELLOWButton.Text = 'YELLOW';
            app.GREENButton = uibutton(app.ManualmodeSettingsPanel, 'push');
            app.GREENButton.ButtonPushedFcn = createCallbackFcn(app, @GREENButtonPushed, true);
            app.GREENButton.Text = 'GREEN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Traffic_Light_Arduino(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end